classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ProcessedImagePanel   matlab.ui.container.Panel
        ProcessedHistogram    matlab.ui.control.UIAxes
        ProcessedImage        matlab.ui.control.UIAxes
        OriginalImagePanel    matlab.ui.container.Panel
        OriginalImage         matlab.ui.control.UIAxes
        OriginalHistogram     matlab.ui.control.UIAxes
        OptionsPanel          matlab.ui.container.Panel
        FilenameLabel         matlab.ui.control.Label
        ImageReferenceButton  matlab.ui.control.Button
        gammaEditField        matlab.ui.control.NumericEditField
        gammaEditFieldLabel   matlab.ui.control.Label
        cEditField            matlab.ui.control.NumericEditField
        cEditFieldLabel       matlab.ui.control.Label
        ProcessButton         matlab.ui.control.Button
        bEditField            matlab.ui.control.NumericEditField
        bEditFieldLabel       matlab.ui.control.Label
        aEditField            matlab.ui.control.NumericEditField
        aEditFieldLabel       matlab.ui.control.Label
        ChooseImageButton     matlab.ui.control.Button
        ModeDropDown          matlab.ui.control.DropDown
        ModeDropDownLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global img;
            global ref;
            global newImg;
            img = -1;
            ref = -1;
            newImg = -1;
        end

        % Button pushed function: ChooseImageButton
        function ChooseImageButtonPushed(app, event)
            
           global img;
           [filename, pathname] = uigetfile('*.*', 'pick an image');
           filename = strcat(pathname, filename);
           img = imread(filename);
           %get hist
           hist = CustomHist;
           [freq, uniqueVals] = hist.getHistData(img);
           bar(app.OriginalHistogram, uniqueVals(1:end-1), freq);
           imshow(img, 'Parent', app.OriginalImage);
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;
            app.aEditField.Value = 1;
            app.bEditField.Value = 1;
            app.cEditField.Value = 1;
            app.gammaEditField.Value= 1;

            if (value == "Image Negative")
                app.aEditField.Visible = 'off';
                app.bEditField.Visible = 'off';
                app.cEditField.Visible = 'off';
                app.gammaEditField.Visible = 'off';

                app.aEditFieldLabel.Visible = 'off';
                app.bEditFieldLabel.Visible = 'off';
                app.cEditFieldLabel.Visible = 'off';
                app.gammaEditFieldLabel.Visible = 'off';

                app.ImageReferenceButton.Visible = 'off';
                app.FilenameLabel.Visible = 'off';
            elseif (value == "Image Brightening")
                app.aEditField.Visible = 'on';
                app.bEditField.Visible = 'on';
                app.cEditField.Visible = 'off';
                app.gammaEditField.Visible = 'off';

                app.aEditFieldLabel.Visible = 'on';
                app.bEditFieldLabel.Visible = 'on';
                app.cEditFieldLabel.Visible = 'off';
                app.gammaEditFieldLabel.Visible = 'off';

                app.ImageReferenceButton.Visible = 'off';
                app.FilenameLabel.Visible = 'off';
            elseif (value == "Log Transform")
                app.aEditField.Visible = 'off';
                app.bEditField.Visible = 'off';
                app.cEditField.Visible = 'on';
                app.gammaEditField.Visible = 'off';

                app.aEditFieldLabel.Visible = 'off';
                app.bEditFieldLabel.Visible = 'off';
                app.cEditFieldLabel.Visible = 'on';
                app.gammaEditFieldLabel.Visible = 'off';

                app.ImageReferenceButton.Visible = 'off';
                app.FilenameLabel.Visible = 'off';
            elseif (value == "Power Transform")
                app.aEditField.Visible = 'off';
                app.bEditField.Visible = 'off';
                app.cEditField.Visible = 'on';
                app.gammaEditField.Visible = 'on';

                app.aEditFieldLabel.Visible = 'off';
                app.bEditFieldLabel.Visible = 'off';
                app.cEditFieldLabel.Visible = 'on';
                app.gammaEditFieldLabel.Visible = 'on';

                app.ImageReferenceButton.Visible = 'off';
                app.FilenameLabel.Visible = 'off';
            elseif (value == "Contrast Stretching")
                app.aEditField.Visible = 'off';
                app.bEditField.Visible = 'off';
                app.cEditField.Visible = 'off';
                app.gammaEditField.Visible = 'off';

                app.aEditFieldLabel.Visible = 'off';
                app.bEditFieldLabel.Visible = 'off';
                app.cEditFieldLabel.Visible = 'off';
                app.gammaEditFieldLabel.Visible = 'off';

                app.ImageReferenceButton.Visible = 'off';
                app.FilenameLabel.Visible = 'off';
            elseif (value == "Histogram Specification")
                app.aEditField.Visible = 'off';
                app.bEditField.Visible = 'off';
                app.cEditField.Visible = 'off';
                app.gammaEditField.Visible = 'off';

                app.aEditFieldLabel.Visible = 'off';
                app.bEditFieldLabel.Visible = 'off';
                app.cEditFieldLabel.Visible = 'off';
                app.gammaEditFieldLabel.Visible = 'off';

                app.ImageReferenceButton.Visible = 'on';
                app.FilenameLabel.Visible = 'on';
            else
                app.aEditField.Visible = 'off';
                app.bEditField.Visible = 'off';
                app.cEditField.Visible = 'off';
                app.gammaEditField.Visible = 'off';

                app.aEditFieldLabel.Visible = 'off';
                app.bEditFieldLabel.Visible = 'off';
                app.cEditFieldLabel.Visible = 'off';
                app.gammaEditFieldLabel.Visible = 'off';

                app.ImageReferenceButton.Visible = 'off';
                app.FilenameLabel.Visible = 'off';
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            global newImg;
            global img;
            global ref;
            value = app.ModeDropDown.Value;
            imageManip = ImageManipulation;
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            c = app.cEditField.Value;
            gamma = app.gammaEditField.Value;
            %img checking, if null then show popup error
            if (isequal(img, false))
                msgbox("Please choose an image to be processed");
            end
            if (value == "Image Negative")
                newImg = imageManip.imageNegative(img);
            elseif (value == "Image Brightening")
                newImg = imageManip.imageBrighten(img, a, b);
            elseif (value == "Log Transform")
                newImg = imageManip.logTransformation(img, c);
            elseif (value == "Power Transform")
                newImg = imageManip.powerTransformation(img, c, gamma);
            elseif (value == "Contrast Stretching")
                newImg = imageManip.contrastStretching(img);
            elseif (value == "Histogram Equalization")
                newImg = imageManip.histogramEqual(img);
            elseif (value == "Histogram Specification")
                newImg = imageManip.histogramSpecification(img, ref);
            end

            %get hist
            hist = CustomHist;
            [freq, uniqueVals] = hist.getHistData(newImg);
            bar(app.ProcessedHistogram, uniqueVals(1:end-1), freq);
            imshow(newImg, 'Parent', app.ProcessedImage);

        end

        % Button pushed function: ImageReferenceButton
        function ImageReferenceButtonPushed(app, event)
           global ref;
           [filename, pathname] = uigetfile('*.*', 'pick an image');
           filename = strcat(pathname, filename);
           ref = imread(filename);
           app.FilenameLabel.Text = strcat("Filename : ", filename);
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.ModeDropDownLabel = uilabel(app.OptionsPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.OptionsPanel);
            app.ModeDropDown.Items = {'Image Brightening', 'Image Negative', 'Log Transform', 'Contrast Stretching', 'Power Transform', 'Histogram Equalization', 'Histogram Specification'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Image Brightening';
            app.ChooseImageButton = uibutton(app.OptionsPanel, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButtonPushed, true);
            app.ChooseImageButton.Text = 'Choose Image';
            app.aEditFieldLabel = uilabel(app.OptionsPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.aEditField.Value = 1;
            app.bEditFieldLabel = uilabel(app.OptionsPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.bEditField.Value = 1;
            app.ProcessButton = uibutton(app.OptionsPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.cEditFieldLabel = uilabel(app.OptionsPanel);
            app.cEditFieldLabel.Visible = 'off';
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.cEditField.Visible = 'off';
            app.gammaEditFieldLabel = uilabel(app.OptionsPanel);
            app.gammaEditFieldLabel.Visible = 'off';
            app.gammaEditFieldLabel.Text = 'gamma';
            app.gammaEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.gammaEditField.Visible = 'off';
            app.ImageReferenceButton = uibutton(app.OptionsPanel, 'push');
            app.ImageReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @ImageReferenceButtonPushed, true);
            app.ImageReferenceButton.Visible = 'off';
            app.ImageReferenceButton.Text = 'Image Reference';
            app.FilenameLabel = uilabel(app.OptionsPanel);
            app.FilenameLabel.Visible = 'off';
            app.FilenameLabel.Text = 'Filename : ';
            app.OriginalImagePanel = uipanel(app.UIFigure);
            app.OriginalImagePanel.Title = 'Original Image';
            app.OriginalHistogram = uiaxes(app.OriginalImagePanel);
            title(app.OriginalHistogram, 'Histogram')
            xlabel(app.OriginalHistogram, 'X')
            ylabel(app.OriginalHistogram, 'Y')
            zlabel(app.OriginalHistogram, 'Z')
            app.OriginalImage = uiaxes(app.OriginalImagePanel);
            title(app.OriginalImage, 'Image')
            xlabel(app.OriginalImage, 'X')
            ylabel(app.OriginalImage, 'Y')
            zlabel(app.OriginalImage, 'Z')
            app.ProcessedImagePanel = uipanel(app.UIFigure);
            app.ProcessedImagePanel.Title = 'Processed Image';
            app.ProcessedImage = uiaxes(app.ProcessedImagePanel);
            title(app.ProcessedImage, 'Image')
            xlabel(app.ProcessedImage, 'X')
            ylabel(app.ProcessedImage, 'Y')
            zlabel(app.ProcessedImage, 'Z')
            app.ProcessedHistogram = uiaxes(app.ProcessedImagePanel);
            title(app.ProcessedHistogram, 'Histogram')
            xlabel(app.ProcessedHistogram, 'X')
            ylabel(app.ProcessedHistogram, 'Y')
            zlabel(app.ProcessedHistogram, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end